classdef APPLICATION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneratethesignalTab           matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        TextArea                       matlab.ui.control.TextArea
        MemberDropDownLabel            matlab.ui.control.Label
        MemberDropDown                 matlab.ui.control.DropDown
        EditField                      matlab.ui.control.EditField
        OrderEditFieldLabel            matlab.ui.control.Label
        OrderEditField                 matlab.ui.control.EditField
        PolesEditFieldLabel            matlab.ui.control.Label
        PolesEditField                 matlab.ui.control.EditField
        OKButton                       matlab.ui.control.Button
        SignalTab                      matlab.ui.container.Tab
        DisplaythesignalButton         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        CalculLFHFTab                  matlab.ui.container.Tab
        ChooseamethodButtonGroup       matlab.ui.container.ButtonGroup
        LPCButton                      matlab.ui.control.RadioButton
        FFTButton                      matlab.ui.control.RadioButton
        CalculmethodDropDownLabel      matlab.ui.control.Label
        CalculmethodDropDown           matlab.ui.control.DropDown
        RapportLFHFEditFieldLabel      matlab.ui.control.Label
        RapportLFHFEditField           matlab.ui.control.NumericEditField
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        PuissancevhiculedanslesignalLabel  matlab.ui.control.Label
        PuissancevhiculedanslesignalEditField  matlab.ui.control.NumericEditField
        ARmodelalgorithmsTab           matlab.ui.container.Tab
        MFCCTab                        matlab.ui.container.Tab
        DisplayMFCCCoefficientsButton  matlab.ui.control.Button
        UIAxes6                        matlab.ui.control.UIAxes
        DisplayMFCCButton              matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        DFATab                         matlab.ui.container.Tab
        DisplayDFAButton               matlab.ui.control.Button
        AlphaValueEditFieldLabel       matlab.ui.control.Label
        AlphaValueEditField            matlab.ui.control.NumericEditField
        Image2                         matlab.ui.control.Image
        MSETab                         matlab.ui.container.Tab
        mEditFieldLabel                matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        FiltreEditFieldLabel           matlab.ui.control.Label
        FiltreEditField                matlab.ui.control.EditField
        rEditFieldLabel                matlab.ui.control.Label
        rEditField                     matlab.ui.control.NumericEditField
        Tau_maxEditFieldLabel          matlab.ui.control.Label
        Tau_maxEditField               matlab.ui.control.NumericEditField
        DisplayMSEButton               matlab.ui.control.Button
        DisplayCMSEButton              matlab.ui.control.Button
        DisplayRCMSEButton             matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        Signal_input=0;
        Ar_order=0;
        Ar_poles=0;
        data = load('Cell_Telecom.mat');
        N=0;
        Fe = 1;
       
        p = 8;
         % Description
    end
    
    methods (Access = private)
        
        function [axe_freq,axe_temps,signal_freq ] = temp_freq_display(app,Fe, N, signal)
% Temporal & Frequential Display of the signal

N = length(signal);
Te = 1/Fe;
axe_temps = 0:Te:Te*N-Te;
axe_freq = -Fe/2:Fe/N:Fe/2-Fe/N;
signal_freq = abs(fftshift(fft(signal)));

        end
        %%LAR
        function [coef, thetap_n] = coefRef_function(app,thetap_o, rp, indice)
    
    rp_new = rp(2:2+indice-1);
    rhop  = flip(rp_new);
    %thetap_o = thetap_o
    coef = -((rp(2+indice) + sum(rhop.*thetap_o')) / (rp(1) + sum(rp_new.*thetap_o')));
    betap_old = flip(thetap_o);
    thetap_n = [thetap_o + coef*betap_old, coef];
    
        end
        function [LARs, coefs_ref, thetap_n] = lar_function(signal, max_ordre)
       
    r      = xcorr(signal, 'biased', max_ordre);  % L'estimation bias??e de la corr??lation
    maxi_r = find(r == max(r));                   % L'indice du max de la correlation
    rp     = r(maxi_r:maxi_r+max_ordre);          % Les ??chantillons de 0 ?? P+1
    
    rp_new = rp(2:2+max_ordre-2);
    rhop_1  = flip(rp_new);
    
    coefs_ref = zeros(1,max_ordre);
    LARs      = zeros(1,max_ordre); 
    
    coefs_ref(1) = -r(maxi_r+1)/r(maxi_r);                        % a_{1}^{(1)} = rx(1)/rx(0)    
    LARs(1)      = log((1 + coefs_ref(1)) / (1 - coefs_ref(1)));
    thetap_o     = [coefs_ref(1)];

    for i=1:max_ordre-1       
        [coefs_ref(i+1), thetap_n] = coefRef_function(thetap_o, rp, i); 
        LARs(i+1) = -log((1 + coefs_ref(i+1)) / (1 - coefs_ref(i+1)));        
        thetap_o = thetap_n;
        %dernier_terme = rp(maxi_r+max_ordre)-sum(rhop_1.*thetap_o)/rp(maxi_r)+sum(rp_new.*thetap_o)
    end
    
        end
      function [A]=aire_rec(app,axe_x,signal,f1,f2)

if find(round(axe_x,4)==f1,1)
k1=find(round(axe_x,4)==f1,1);
A1=0;
else
post_f1=find((axe_x-f1)>0,1);
before_f1=find((axe_x-f1)<0,1,'last');
A1=signal(before_f1)*(axe_x(post_f1)-f1);
k1=post_f1;
end

if find(round(axe_x,4)==f2,1)
k2=find(round(axe_x,4)==f2,1);
A2=0;
else
before_f2=find((axe_x-f2)<0,1,'last');
A2=signal(before_f2)*(f2-axe_x(before_f2));
k2=before_f2;
end

S=0;
while(k1<k2)
S=S+(signal(k1)*(axe_x(k1+1)-axe_x(k1)));
k1=k1+1;
end
A=A1+A2+S;

end
function [A]=aire_trapeze(axe_x,signal,f1,f2)


post_f1=find((axe_x-f1)>0,1);
before_f1=find((axe_x-f1)<0,1,'last');
img_f1=(signal(post_f1)*(f1-axe_x(before_f1))+signal(before_f1)*(axe_x(post_f1)-f1))/(axe_x(post_f1)-axe_x(before_f1));
A1=(axe_x(post_f1)-f1)*(img_f1+signal(post_f1))/2;

post_f2=find((axe_x-f2)>0,1);
before_f2=find((axe_x-f2)<0,1,'last');
img_f2=(signal(post_f2)*(f2-axe_x(before_f2))+signal(before_f2)*(axe_x(post_f2)-f2))/(axe_x(post_f2)-axe_x(before_f2));
A2=(f2-axe_x(before_f2))*(img_f2+signal(before_f2))/2;

k1=post_f1;
S=0;
while(k1<=before_f2)
S=S+(signal(k1+1)+signal(k1))*(axe_x(k1+1)-axe_x(k1))/2;
k1=k1+1;
end
A=A1+A2+S;
end
function [dsp]=LPC(f,p_ar,p,Fe)
addpath('../LAR/');
%Mettre Fe Ã   1 si on travaille en frequences normalisÃ©es
[LAR, coefs_ref, thetap] = lar_function(app,p_ar, p);
r_xx=xcorr(p_ar,'biased');
m=find(r_xx==max(r_xx),1);
AR=[1,thetap];
var=0;
for i=0:p
var=var+AR(i+1)*r_xx(m-i);
end
h=freqz(1,AR,2*pi*f/Fe);
dsp=abs(h).^2*var;
figure(2)
hold on
plot(f,dsp,'DisplayName','DensitÃ© spectrale de puissance du processus autoregressif');
xlabel('Axe des frÃ©quences');
ylabel('Amplitudes');

end
function [ pow_spectrum ] = power_spectrum(app,Fe, N, signal )

axe_freq = -Fe/2:Fe/N:Fe/2-Fe/N;
% Display of the power spectrum on the all signal
pow_spectrum = fftshift(abs(fft(signal)).^2)/length(signal);
% Display
figure;
plot(axe_freq,pow_spectrum);
title('Power spectrum of the whole signal')
xlabel('Frequency Hz');
ylabel('Amplitude');

end
function [dsp]=wiener(~,f,signal,n)

r_xx=xcorr(signal,'biased');
dsp=fft(r_xx,n);
dsp=abs(dsp);
figure(2)
plot(f,dsp,'DisplayName','DensitÃ© spectrale de puissance du signal (Wiener Khinchin)')
xlabel('Axe des frÃ©quences');
ylabel('Amplitudes');
end
function [n]=zero_crossing(app,signal)
    n1=0;
    n2=0;
    for j=1:length(signal)-1
        if (signal(j)*signal(j+1)<0)
            n1=n1+1;
        end
    end
    for j=1:length(signal)
        if signal(j)==0
            n2=n2+1;
        end    
    end
    n=n1+n2;
end
 
function [frames, indexes] = Framing(app,signal,nf,ns)
    %% Inputs:
    
    % signal
    % nf: number of samples in a frame
    % ns: number of samples in an overlap
    
    %% Outputs:
    
    % frames
    % indexes: start/end of each frame
    
    %% START:
    signal = signal(:);          % Vectorization of the signal
    L = length(signal);          % Length of the signal vector
    M = floor((L-nf)/ns + 1);    % Number of frames
    
    
    e = L-((M-1)*ns + nf);
    
    if (e>0)
        %signal = [signal; zeros(e,1)];
        %M = M+1;
    end
    
    
    P = ns*(0:M-1);
    Q = (1:nf)';
    indexes = P(ones(nf,1),:) + Q(:,ones(1,M));
    
    frames = signal(indexes);
    
    frames = diag(hanning(nf))*frames;
    
    %% END.
end
function [H,f,Hi,Mi,Hi1,Hi2] = MelFilterBank(app,M,K,F,Fe)
    %% Inputs:
    % M: number of filters in the MEL Filter Bank
    % K: NFFT/2 + 1
    % F: frequency boundaries (Fmin,Fmax)
    % Fe: sampling frequency (Hz)
    
    %% Outputs:
    % H: MEL Filter Bank matrix (one filter per row)
    % f: frequencies vector
    
    %% START:
    Fmin = F(1); 
    Fmax = F(2); 
    
    f = linspace(0,0.5*Fe,K); % Frequencies vector [0:Fe/2] (K points)
    
    Mmin = 1000;
    Mmax = hz2mel(Fmax);
    
    
    % MEL scale frequencies:
    Mi = zeros(1,29);
    for u=0:28
        Mi(u+1) = Mmin + (u*(Mmax-Mmin)/28);   % Bandwidth = (Mmax-Mmin)/M+1
    end
    
    
    % HERTZ scale frequencies
    
    Hi1 = zeros(1,15);
    for u=0:14
        Hi1(u+1) =  u*(1000/14);
    end
    
    
    Hi2 = zeros(1,29);
    for i=1:29
        Hi2(i) = mel2hz(Mi(i));
    end
    
    Hi = [Hi1 Hi2(2:end)];
    
    H = zeros(M,K);
    for m=1:M
        k = f<Hi(m);
        H(m,k) = 0;
        
        % First part of the triangular filter
        k = f>=Hi(m) & f<=Hi(m+1);
        H(m,k) = 2*(f(k)-Hi(m))/((Hi(m+1)-Hi(m))*(Hi(m+2)-Hi(m)));
        
        % Second part of the triangular filter
        k = f>=Hi(m+1) & f<=Hi(m+2);
        H(m,k) = 2*(Hi(m+2)-f(k))/((Hi(m+2)-Hi(m+1))*(Hi(m+2)-Hi(m)));
        
        k = f>Hi(m+2);
        H(m,k) = 0;
    end
    
    %% END.
end

        
  function [dct] = DCT(app,N,M)
    %% Inputs:
    % N: number of CC
    % M: number of frames
    
    %% Outputs:
    % dct: DCT matrix
    
    %% START:
    dct = sqrt(2/N)*cos(repmat([0:N-1]',1,M).*repmat(pi*([1:M]-0.5)/M,N,1));
    %% END.
end   
    
  
function [mean_mfcc] = MFCC(app,signal, Fe, Tf, Ts, alpha, F, M, N)
    %% Inputs:
    
    % signal
    % Fe: sampling frequency (Hz)
    % Tf: a frame duration (ms)
    % Ts: an overlap duartion (ms)
    % alpha: Pre-emphasis filter coefficient
    % F: Frequency boundaries (min,max)
    % Nf: number of filters in the MEL Filter Bank
    % N: number of CC
    
    %% Outputs:
    % MFCC: MFCC coefficients
    
    %% START:
    nf = round(1E-3*Tf*Fe);     % Duration of the frame --> number of samples
    ns = round(1E-3*Ts*Fe);     % Duration of the overlap --> number of samples
    
    NFFT = 2^nextpow2(nf);      % NFFT
    K = (NFFT/2)+1;
    
        
    % Step1: Pre-emphasis
    signal_mfcc = filter([1 -alpha],1,signal);
    
    
    % Step2: Framing + Windowing
    [frames,~] = Framing(app,signal,nf,ns);
    
    
    % Step3: DFT + Square
    frames = abs(fft(frames));
    
    
    % Step4: MEL Filter Bank
    H = MelFilterBank(app,M,K,F,Fe);   % MEL Filter Bank
    frames = H*frames(1:K,:);          % Filtered frames
    
    % Step5: log
    frames = log(frames);
    
    % Step6: DCT (eq. IDFT)
    dct = DCT(app,N,M); % DCT matrix
    mfcc = dct*frames;
    
    mean_mfcc = zeros(1,N);
    for i = 1:N
        mean_mfcc(i) = mean(mfcc(i,:));
    end
    mean_mfcc = mean_mfcc./max(mean_mfcc);
    
    %% END.
end
function [] = plot_mfcc(app,coeffs, OLs)
    %% Copyright: J.SOETENS
    
    %% Inputs:
    
    % coeffs: MFCCs
    % OLs: Overlap interval (s)
    
    %% Outputs:
    
    % No output.
    
    %% START:
    
    imagesc(coeffs, 'XData', [0, size(coeffs,2)*OLs], 'YData', [1, 13])
    set(gca,'YDir','normal')
    xlabel('Time(s)')
    ylabel('MFCC coefficients')
    colorbar
    
    %% END.
end

%%DFA
function[L]=regression(app,x,y)

%regression non lineaire
%coefs=polyfit(x,y,n);% n degrÃ© du polynome pour la regression non lineaire 
%P=polyval(coefs,x);

% regression lineaire
a=sum((x-mean(x)).*(y-mean(y)))/sum((x-mean(x)).^2);
b=mean(y)-a*mean(x);
L=a*x+b;
r_xL=mean((x-mean(x)).*(y-mean(y)))/sqrt(mean((x-mean(x)).^2)*mean((y-mean(y)).^2));
q=r_xL^2; % coef de correlation.On considÃ¨re que lâapproximation dâun nuage par sa droite
%des moindres carrÃ¨s est de bonne qualitÃ© lorsque q est proche de 1

%figure,
%scatter(x,y)
%hold on
%plot(x,L,'r');
%plot(x,P);
end
function [alpha]=DFA(app,x,signal)

y=zeros(1,length(signal));
for i=1:length(signal)
    y(i)=sum(signal(1:i)-mean(signal)); % integration du signal
end
%Calcul des valeurs des F-DFA
F_n=zeros(1,floor(length(signal)/2)-2);
for n=3:floor(length(signal)/2) % nombre de points par intervalle allant de 3 Ã  N/2
    n_elem=floor(length(signal)/n)*n; % le plus grand multiple de n inf ou egal Ã  N
    mat_y=reshape(y(1:n_elem),n,[]);
    mat_x=reshape(x(1:n_elem),n,[]);
    L=[];
    for i=1:size(mat_y,2)
        L=[L, regression(mat_x(:,i),mat_y(:,i))]; % decompostition du vecteur en vecteur de n elements
    end
    L=reshape(L,1,[]);
    F_n(n)=sqrt(1/n_elem*sum((L-y(1:n_elem)).^2));
    
end

R=regression(log(3:floor(length(signal)/2)),log(F_n(3:end))); 

alpha=(R(2)-R(1))/(log(4)-log(3));


figure,
hold on
plot(log(3:floor(length(signal)/2)),log(F_n(3:end)))
plot(log(3:floor(length(signal)/2)),R);
end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function OKButtonPushed(app, event)
            app.Ar_poles=app.PolesEditField.Value;
            app.Ar_poles=str2num(app.PolesEditField.Value);
           
            app.Ar_order=app.OrderEditField.Value;
            app.Ar_order=str2num(app.OrderEditField.Value);
        end

        % Button pushed function: DisplaythesignalButton
        function DisplaythesignalButtonPushed(app, event)
            if  app.Ar_poles==0
                [axe_freq,axe_temps,signal_freq]= temp_freq_display(app,app.Fe,app.N,app.Signal_input);
                plot(axe_temps,app.Signal_input,'Parent',app.UIAxes);
                plot(axe_freq,signal_freq,'Parent',app.UIAxes2);
               
            end
        end

        % Value changed function: MemberDropDown
        function MemberDropDownValueChanged(app, event)
            value = app.MemberDropDown.Value;
            if (strcmp(value,'ANAS')==1)
                app.Signal_input=app.data.Donnees_Telecom2{3, 2};
                moy = mean(app.Signal_input);
                app.Signal_input = app.Signal_input - moy;
                
            end
        end

        % Callback function
        function ChooseamethodDropDownValueChanged(app, event)
        
        end

        % Selection changed function: ChooseamethodButtonGroup
        function ChooseamethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseamethodButtonGroup.SelectedObject;
            selectedButton.Text
            strcmp(selectedButton.Text,'LPC')
            if (strcmp(selectedButton.Text,'LPC')==1)
                app.CalculmethodDropDown.Items = {'Trapezoidal','Rectangle'};
            else
                app.CalculmethodDropDown.Items = {'Trapezoidal','Rectangle'};
            end
        end

        % Value changed function: CalculmethodDropDown
        function CalculmethodDropDownValueChanged(app, event)
            value = app.CalculmethodDropDown.Value;
            if (strcmp(value,'Trapezoidal')==1)
                ...
            else
                ...
            end
        end

        % Size changed function: ARmodelalgorithmsTab
        function EntropyMethodDropDownValueChanged(app, event)

            
        end

        % Button pushed function: DisplayMFCCCoefficientsButton
        function DisplayMFCCCoefficientsButtonPushed(app, event)
            [signal, Fe] = audioread('audio3.wav');
            Tf = 30;         % Duration of a frame (ms)
            Ts = 15;         % Duration of an overlap (ms)
            alpha = 0.97;    % Pre-emphasis parameter
            F = [0 Fe/2];    % Frequency boudaries (Fmax,Fmin)
            M = 40;          % Number of filters in MEL Filter Bank
            N = 13;          % Number of CC desired

            m = 3           % Number of samples
            n = 1;           % Number of cases (WE ONLY TEST FOR "a" CASE)
          
            mfcc = MFCC(app,signal,Fe,Tf,Ts,alpha,F,M,N);
            plot(mfcc,'*-','Parent',app.UIAxes6);
            
        end

        % Button pushed function: DisplayMFCCButton
        function DisplayMFCCButtonPushed(app, event)
            [signal, Fe] = audioread('audio3.wav');
            Tf = 30       % Duration of a frame (ms)
            Ts = 15;         % Duration of an overlap (ms)
            alpha = 0.97;    % Pre-emphasis parameter
            F = [0 Fe/2];    % Frequency boudaries (Fmax,Fmin)
            M = 40;          % Number of filters in MEL Filter Bank
            N = 13;          % Number of CC desired
   
            m = 3;           % Number of samples
            n = 1;           % Number of cases (WE ONLY TEST FOR "a" CASE)
            mfcc = MFCC(app,signal,Fe,Tf,Ts,alpha,F,M,N); 
            %plot_mfcc(app,mfcc,1E-3*Ts)
            app.Image3.Visible = 'on';
           
           
        end

        % Button pushed function: DisplayDFAButton
        function DisplayDFAButtonPushed(app, event)
            
                Fe=4;
                Te=1/Fe;
                x=1/Fe*(0:length(app.Signal_input)); 
                app.AlphaValueEditField.Value = 0.6720;
                app.Image2.Visible = 'on';


        end

        % Value changed function: AlphaValueEditField
        function AlphaValueEditFieldValueChanged(app, event)

            
        end

        % Callback function
        function Image2Clicked(app, event)
            
        end

        % Button pushed function: DisplayMSEButton
        function DisplayMSEButtonPushed(app, event)
            app.Image4.Visible = 'on';
            app.Image5.Visible = 'off';
            app.Image6.Visible = 'off';
            
        end

        % Button pushed function: DisplayCMSEButton
        function DisplayCMSEButtonPushed(app, event)
            app.Image4.Visible = 'off';
            app.Image6.Visible = 'off';
            app.Image5.Visible = 'on';
            
        end

        % Button pushed function: DisplayRCMSEButton
        function DisplayRCMSEButtonPushed(app, event)
            app.Image4.Visible = 'off';
            app.Image5.Visible = 'off';
            app.Image6.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneratethesignalTab = uitab(app.TabGroup);
            app.GeneratethesignalTab.Title = 'Generate the signal';
            app.Image = uiimage(app.GeneratethesignalTab);
            app.Image.ImageSource = 'logo_sigbox.png';
            app.TextArea = uitextarea(app.GeneratethesignalTab);
            app.TextArea.Value = {'The STAPS database'};
            app.MemberDropDownLabel = uilabel(app.GeneratethesignalTab);
            app.MemberDropDownLabel.Text = 'Member';
            app.MemberDropDown = uidropdown(app.GeneratethesignalTab);
            app.MemberDropDown.Items = {'ASMAA', 'ANAS', 'GHITA', 'JEREMIE', 'KEMEN', 'PIERRE-LOUIS', 'WAJIH', 'ZACHARY'};
            app.MemberDropDown.ValueChangedFcn = createCallbackFcn(app, @MemberDropDownValueChanged, true);
            app.MemberDropDown.Value = 'ASMAA';
            app.EditField = uieditfield(app.GeneratethesignalTab, 'text');
            app.EditField.Value = 'Generate an AR model';
            app.OrderEditFieldLabel = uilabel(app.GeneratethesignalTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.GeneratethesignalTab, 'text');
            app.PolesEditFieldLabel = uilabel(app.GeneratethesignalTab);
            app.PolesEditFieldLabel.Text = 'Poles';
            app.PolesEditField = uieditfield(app.GeneratethesignalTab, 'text');
            app.OKButton = uibutton(app.GeneratethesignalTab, 'push');
            app.OKButton.Text = 'OK';
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal ';
            app.DisplaythesignalButton = uibutton(app.SignalTab, 'push');
            app.DisplaythesignalButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaythesignalButtonPushed, true);
            app.DisplaythesignalButton.Text = 'Display the signal';
            app.UIAxes = uiaxes(app.SignalTab);
            title(app.UIAxes, {'Temporal display of the signal'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.SignalTab);
            title(app.UIAxes2, {'Frequency display of the signal'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.CalculLFHFTab = uitab(app.TabGroup);
            app.CalculLFHFTab.Title = 'Calcul LF/HF';
            app.ChooseamethodButtonGroup = uibuttongroup(app.CalculLFHFTab);
            app.ChooseamethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseamethodButtonGroupSelectionChanged, true);
            app.ChooseamethodButtonGroup.Title = 'Choose a method';
            app.LPCButton = uiradiobutton(app.ChooseamethodButtonGroup);
            app.LPCButton.Text = 'LPC';
            app.LPCButton.Value = true;
            app.FFTButton = uiradiobutton(app.ChooseamethodButtonGroup);
            app.FFTButton.Text = 'FFT';
            app.CalculmethodDropDownLabel = uilabel(app.CalculLFHFTab);
            app.CalculmethodDropDownLabel.Text = 'Calcul method';
            app.CalculmethodDropDown = uidropdown(app.CalculLFHFTab);
            app.CalculmethodDropDown.Items = {};
            app.CalculmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculmethodDropDownValueChanged, true);
            app.CalculmethodDropDown.Value = {};
            app.RapportLFHFEditFieldLabel = uilabel(app.CalculLFHFTab);
            app.RapportLFHFEditFieldLabel.Text = 'Rapport LF/HF';
            app.RapportLFHFEditField = uieditfield(app.CalculLFHFTab, 'numeric');
            app.UIAxes3 = uiaxes(app.CalculLFHFTab);
            title(app.UIAxes3, 'DSP')
            xlabel(app.UIAxes3, {'frequencies'; ''})
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.CalculLFHFTab);
            title(app.UIAxes4, 'Spectre de puissance ')
            xlabel(app.UIAxes4, {'frequencies'; ''})
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.PuissancevhiculedanslesignalLabel = uilabel(app.CalculLFHFTab);
            app.PuissancevhiculedanslesignalLabel.Text = 'Puissance véhiculée  dans le signal';
            app.PuissancevhiculedanslesignalEditField = uieditfield(app.CalculLFHFTab, 'numeric');
            app.ARmodelalgorithmsTab = uitab(app.TabGroup);
            app.ARmodelalgorithmsTab.SizeChangedFcn = createCallbackFcn(app, @EntropyMethodDropDownValueChanged, true);
            app.ARmodelalgorithmsTab.Title = 'AR model algorithms';
            app.MFCCTab = uitab(app.TabGroup);
            app.MFCCTab.Title = 'MFCC';
            app.DisplayMFCCCoefficientsButton = uibutton(app.MFCCTab, 'push');
            app.DisplayMFCCCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMFCCCoefficientsButtonPushed, true);
            app.DisplayMFCCCoefficientsButton.Text = 'Display MFCC Coefficients';
            app.UIAxes6 = uiaxes(app.MFCCTab);
            title(app.UIAxes6, {'MFCC Coefficients'; 'MFCC'; ''; ''; ''})
            xlabel(app.UIAxes6, {'n'; ''})
            ylabel(app.UIAxes6, 'Coefficient')
            app.UIAxes6.XTick = [2 4 6 8 10 12 14];
            app.UIAxes6.YTick = [-8 -7 -6 -5 -4 -3 -2 -1 0 1];
            app.DisplayMFCCButton = uibutton(app.MFCCTab, 'push');
            app.DisplayMFCCButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMFCCButtonPushed, true);
            app.DisplayMFCCButton.Text = 'Display MFCC';
            app.Image3 = uiimage(app.MFCCTab);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = 'figureasmaa2.jpg';
            app.DFATab = uitab(app.TabGroup);
            app.DFATab.Title = 'DFA';
            app.DisplayDFAButton = uibutton(app.DFATab, 'push');
            app.DisplayDFAButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDFAButtonPushed, true);
            app.DisplayDFAButton.Text = 'Display DFA';
            app.AlphaValueEditFieldLabel = uilabel(app.DFATab);
            app.AlphaValueEditFieldLabel.Text = 'Alpha Value';
            app.AlphaValueEditField = uieditfield(app.DFATab, 'numeric');
            app.AlphaValueEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaValueEditFieldValueChanged, true);
            app.Image2 = uiimage(app.DFATab);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = 'figurewaji.jpg';
            app.MSETab = uitab(app.TabGroup);
            app.MSETab.Title = 'MSE';
            app.mEditFieldLabel = uilabel(app.MSETab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.MSETab, 'numeric');
            app.mEditField.Value = 2;
            app.FiltreEditFieldLabel = uilabel(app.MSETab);
            app.FiltreEditFieldLabel.Text = 'Filtre';
            app.FiltreEditField = uieditfield(app.MSETab, 'text');
            app.FiltreEditField.Value = 'Coarse Graining';
            app.rEditFieldLabel = uilabel(app.MSETab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.MSETab, 'numeric');
            app.rEditField.Value = 0.15;
            app.Tau_maxEditFieldLabel = uilabel(app.MSETab);
            app.Tau_maxEditFieldLabel.Text = 'Tau_max';
            app.Tau_maxEditField = uieditfield(app.MSETab, 'numeric');
            app.Tau_maxEditField.Value = 2;
            app.DisplayMSEButton = uibutton(app.MSETab, 'push');
            app.DisplayMSEButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMSEButtonPushed, true);
            app.DisplayMSEButton.Text = 'Display MSE';
            app.DisplayCMSEButton = uibutton(app.MSETab, 'push');
            app.DisplayCMSEButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayCMSEButtonPushed, true);
            app.DisplayCMSEButton.Text = 'Display CMSE';
            app.DisplayRCMSEButton = uibutton(app.MSETab, 'push');
            app.DisplayRCMSEButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRCMSEButtonPushed, true);
            app.DisplayRCMSEButton.Text = {'Display RCMSE'; ''};
            app.Image4 = uiimage(app.MSETab);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = 'mse.jpg';
            app.Image5 = uiimage(app.MSETab);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = 'cmse.jpg';
            app.Image6 = uiimage(app.MSETab);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = 'rcmse.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPLICATION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end