classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlotLSPButton                 matlab.ui.control.Button
        PlotISPButton                 matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        SaisissezlescoeffAREditFieldLabel  matlab.ui.control.Label
        SaisissezlescoeffAREditField  matlab.ui.control.EditField
        ORDREDUARSwitchLabel          matlab.ui.control.Label
        ORDREDUARSwitch               matlab.ui.control.Switch
        Button                        matlab.ui.control.Button
        coeffAREditFieldLabel         matlab.ui.control.Label
        coeffAREditField              matlab.ui.control.EditField
        OKButton                      matlab.ui.control.Button
        ordreEditFieldLabel           matlab.ui.control.Label
        ordreEditField                matlab.ui.control.EditField
    end

    
    methods (Access = public)
        
        function LSP_fct(app,ordre,coefficients)
            n=ordre+1;
polynom_a_1surz = flip(coefficients);
racines_a_1surz = roots(polynom_a_1surz);

for k =1:ordre
    racines_a(k) = 1/racines_a_1surz(k);
end
if mod(ordre,2)==0
% Polynome P


polynom_p_1surz_LSP=[];
polynom_p_1surz_LSP=[polynom_p_1surz_LSP coefficients(1)];
for i=2:(n+1)/2
polynom_p_1surz_LSP =[polynom_p_1surz_LSP coefficients(i)+coefficients(n+2-i)]; 
end 
polynom_p_1surz_LSP=[polynom_p_1surz_LSP flip(polynom_p_1surz_LSP)];
racines_p_1surz_LSP = roots(polynom_p_1surz_LSP);
for k =1:n
    racines_p_LSP(k) = 1/racines_p_1surz_LSP(k);
end

% Polynome Q
polynom_q_1surz_LSP=[];
polynom_q_1surz_LSP=[polynom_q_1surz_LSP coefficients(1)];
for i=2:(n+1)/2
polynom_q_1surz_LSP =[polynom_q_1surz_LSP coefficients(i)-coefficients(n+2-i)]; 
end 
polynom_q_1surz_LSP=[polynom_q_1surz_LSP flip(-polynom_q_1surz_LSP)];
racines_q_1surz_LSP = roots(polynom_q_1surz_LSP);
for k =1:n
    racines_q_LSP(k) = 1/racines_q_1surz_LSP(k);
end
else


% Polynome P
polynom_p_1surz_LSP=[];
polynom_p_1surz_LSP=[polynom_p_1surz_LSP coefficients(1)];
for i=2:length(coefficients)/2+1
polynom_p_1surz_LSP =[polynom_p_1surz_LSP coefficients(i)+coefficients(length(coefficients)+2-i)]; 
end 
polynom_p_1surz_LSP=[polynom_p_1surz_LSP flip(polynom_p_1surz_LSP(1:end-1))];
racines_p_1surz_LSP = roots(polynom_p_1surz_LSP);
for k =1:n
    racines_p_LSP(k) = 1/racines_p_1surz_LSP(k)
end

% Polynome Q
polynom_q_1surz_LSP=[];
polynom_q_1surz_LSP=[polynom_q_1surz_LSP coefficients(1)];
for i=2:length(coefficients)/2+1
polynom_q_1surz_LSP =[polynom_q_1surz_LSP coefficients(i)-coefficients(length(coefficients)+2-i)]; 
end 
polynom_q_1surz_LSP=[polynom_q_1surz_LSP flip(-polynom_q_1surz_LSP(1:end-1))];

racines_q_1surz_LSP = roots(polynom_q_1surz_LSP);
for k =1:n
    racines_q_LSP(k) = 1/racines_q_1surz_LSP(k)
end
end
 %Plot
blue = [0, 0.4470, 0.7410];
red = [0.8500, 0.3250, 0.0980];
yellow = [0.9290, 0.6940, 0.1250];
black = [0.25, 0.25, 0.25];

figure
plot(racines_a,'*','MarkerSize',7,'Color',blue)
hold on
plot(racines_p_LSP,'+','MarkerSize',7,'Color',red)
hold on
plot(racines_q_LSP,'o','MarkerSize',7,'Color',yellow)
title('Racines des polynomes A(z), P(z) et Q(z) (partie LSP)'); 

hold on
plot (cos([0:0.01:2*pi]),sin([0:0.01:2*pi]),'--','Color',black) % cercle unite

% Trace des rayons
for k = 1:ordre
    hold on
    plot([0 real(racines_a(k))],[0 imag(racines_a(k))],'Color',blue,'Linewidth',1)
end

for k =1:n
    hold on
    plot([0 real(racines_p_LSP(k))],[0 imag(racines_p_LSP(k))],'Color',red,'Linewidth',1)
    hold on
    plot([0 real(racines_q_LSP(k))],[0 imag(racines_q_LSP(k))],'Color',yellow,'Linewidth',1)
end
legend({'A(z)','P(z)','Q(z)'},'Location','eastoutside');
axis equal
ylim([-1.1 1.1])            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotLSPButton
        function PlotLSPButtonPushed(app, event)
            % Parametres
N = 5000; % nombre d'echantillons
p = 4; % ordre
z = 0:1:N-1;
sigma = 1;

% D?finition des poles 
R1=0.8;
R2=0.7;
theta1=pi/3;
theta2=pi/4;
p1=R1*exp(1i*theta1);
p2=R1*exp(-1i*theta1);
p3=R2*exp(1i*theta2);
p4=R2*exp(-1i*theta2);

% Decompostion en elements simples 
a0=1;
a1= -(p1+p2+p3+p4);
a2=(p1*p2)+(p3*p4)+(p1+p2)*(p3+p4);
a3=(-p1*p2*(p3+p4))+(-p3*p4*(p1+p2));
a4=p1*p2*p3*p4;
Poles=[1 a1 a2 a3 a4];
Zeros=[1 0 0 0 0 ];
 %% Parametres alternatifs : paires de raies spectrales
 
% Polynome A
polynom_a_1surz = [a4 a3 a2 a1 a0];
racines_a_1surz = roots(polynom_a_1surz);
for k =1:4
    racines_a(k) = 1/racines_a_1surz(k);
end
 % Polynome A
polynom_a_1surz = [a4 a3 a2 a1 a0];
racines_a_1surz = roots(polynom_a_1surz);
for k =1:4
    racines_a(k) = 1/racines_a_1surz(k);
end
% Polynome P
polynom_p_1surz = [a0 (a1+a4) (a2+a3) (a2+a3) (a1+a4) a0];
racines_p_1surz = roots(polynom_p_1surz);
for k =1:5
    racines_p(k) = 1/racines_p_1surz(k);
end

% Polynome Q
polynom_q_1surz = [-a0 (a4-a1) (a3-a2) (a2-a3) (a1-a4) a0];
racines_q_1surz = roots(polynom_q_1surz);
for k =1:5
    racines_q(k) = 1/racines_q_1surz(k);
end
blue = [0, 0.4470, 0.7410];
red = [0.8500, 0.3250, 0.0980];
yellow = [0.9290, 0.6940, 0.1250];
black = [0.25, 0.25, 0.25];
figure
plot(racines_a,'*','MarkerSize',7,'Color',blue)
hold on
plot(racines_p,'+','MarkerSize',7,'Color',red)
hold on
plot(racines_q,'o','MarkerSize',7,'Color',yellow)
title('Racines des polynomes A(z), P(z) et Q(z) (partie LSP)'); 

hold on
plot (cos([0:0.01:2*pi]),sin([0:0.01:2*pi]),'--','Color',black) % cercle unite

% Trace des rayons
for k = 1:4
    hold on
    plot([0 real(racines_a(k))],[0 imag(racines_a(k))],'Color',blue,'Linewidth',1)
end

for k =1:5
    hold on
    plot([0 real(racines_p(k))],[0 imag(racines_p(k))],'Color',red,'Linewidth',1)
    hold on
    plot([0 real(racines_q(k))],[0 imag(racines_q(k))],'Color',yellow,'Linewidth',1)
end
legend({'A(z)','P(z)','Q(z)'},'Location','eastoutside');
axis equal
ylim([-1.1 1.1])


        end

        % Value changed function: ORDREDUARSwitch
        function ORDREDUARSwitchValueChanged(app, event)
            
        end

        % Button pushed function: PlotISPButton
        function PlotISPButtonPushed(app, event)
            %% ISP
            % Parametres
N = 5000; % nombre d'echantillons
p = 4; % ordre
z = 0:1:N-1;
sigma = 1;

% D?finition des poles 
R1=0.8;
R2=0.7;
theta1=pi/3;
theta2=pi/4;
p1=R1*exp(1i*theta1);
p2=R1*exp(-1i*theta1);
p3=R2*exp(1i*theta2);
p4=R2*exp(-1i*theta2);            
a0=1;
a1= -(p1+p2+p3+p4);
a2=(p1*p2)+(p3*p4)+(p1+p2)*(p3+p4);
a3=(-p1*p2*(p3+p4))+(-p3*p4*(p1+p2));
a4=p1*p2*p3*p4;
Poles=[1 a1 a2 a3 a4];
Zeros=[1 0 0 0 0 ];
  % Polynome A
polynom_a_1surz = [a4 a3 a2 a1 a0];
racines_a_1surz = roots(polynom_a_1surz);
for k =1:4
    racines_a(k) = 1/racines_a_1surz(k);
end
% Polynome P
polynom_p_1surz_ISP = [ (a0+a4) (a1+a3) (a2+a2) (a1+a3) (a0+a4) ];
racines_p_1surz_ISP = roots(polynom_p_1surz_ISP);
for k =1:4
    racines_p_ISP(k) = 1/racines_p_1surz_ISP(k);
end

% Polynome Q      [-a0 (a4-a1) (a3-a2) (a2-a3) (a1-a4) a0]
polynom_q_1surz_ISP = [(-a0+a4) (-a1+a3) 0 (a1-a3) (a0-a4)];
racines_q_1surz_ISP = roots(polynom_q_1surz_ISP);
for k =1:4
    racines_q_ISP(k) = 1/racines_q_1surz_ISP(k);
end

%Construction de vecteurs d'arguments de P et Q :
arg_p_ISP=angle(racines_p_ISP);
arg_q_ISP=angle(racines_q_ISP);

%Reconstruction des coeff AR

%% PLOT
blue = [0, 0.4470, 0.7410];
red = [0.8500, 0.3250, 0.0980];
yellow = [0.9290, 0.6940, 0.1250];
black = [0.25, 0.25, 0.25];

figure
plot(racines_a,'*','MarkerSize',7,'Color',blue)
hold on
plot(racines_p_ISP,'+','MarkerSize',7,'Color',red)
hold on
plot(racines_q_ISP,'o','MarkerSize',7,'Color',yellow)
title('Racines des polynomes A(z), P(z) et Q(z) (partie ISP)'); 

hold on
plot (cos([0:0.01:2*pi]),sin([0:0.01:2*pi]),'--','Color',black) % cercle unite

% Trace des rayons
for k = 1:4
    hold on
    plot([0 real(racines_a(k))],[0 imag(racines_a(k))],'Color',blue,'Linewidth',1)
end

for k =1:4
    hold on
    plot([0 real(racines_p_ISP(k))],[0 imag(racines_p_ISP(k))],'Color',red,'Linewidth',1)
    hold on
    plot([0 real(racines_q_ISP(k))],[0 imag(racines_q_ISP(k))],'Color',yellow,'Linewidth',1)
end
legend({'A(z)','P(z)','Q(z)'},'Location','eastoutside');
axis equal
ylim([-1.1 1.1])
    
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             a=app.EditField_2.Value;
           ac=[]
  b=erase(a,{'[',']'})
  a=split(b,{' ',','})
  ac = str2double(a)';
    app.Label.Text=num2str(sum(ac))
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Poles=app.coeffAREditField.Value;
            Poles=str2num(app.coeffAREditField.Value);
            orde=app.ordreEditField.Value;
            ordre=str2num(app.ordreEditField.Value);
            LSP_fct(app,ordre,Poles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotLSPButton = uibutton(app.UIFigure, 'push');
            app.PlotLSPButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLSPButtonPushed, true);
            app.PlotLSPButton.Text = 'Plot LSP ';
            app.PlotISPButton = uibutton(app.UIFigure, 'push');
            app.PlotISPButton.ButtonPushedFcn = createCallbackFcn(app, @PlotISPButtonPushed, true);
            app.PlotISPButton.Text = 'Plot ISP';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'LSP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ISP')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SaisissezlescoeffAREditFieldLabel = uilabel(app.UIFigure);
            app.SaisissezlescoeffAREditFieldLabel.Text = 'Saisissez les coeff AR';
            app.SaisissezlescoeffAREditField = uieditfield(app.UIFigure, 'text');
            app.ORDREDUARSwitchLabel = uilabel(app.UIFigure);
            app.ORDREDUARSwitchLabel.Text = 'ORDRE DU AR';
            app.ORDREDUARSwitch = uiswitch(app.UIFigure, 'slider');
            app.ORDREDUARSwitch.Items = {'Pair', 'Impair'};
            app.ORDREDUARSwitch.ValueChangedFcn = createCallbackFcn(app, @ORDREDUARSwitchValueChanged, true);
            app.ORDREDUARSwitch.Value = 'Pair';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.coeffAREditFieldLabel = uilabel(app.UIFigure);
            app.coeffAREditFieldLabel.Text = 'coeff AR';
            app.coeffAREditField = uieditfield(app.UIFigure, 'text');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ordreEditFieldLabel = uilabel(app.UIFigure);
            app.ordreEditFieldLabel.Text = 'ordre';
            app.ordreEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end