classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SIGNAUXBIOMEDICAUXMenu       matlab.ui.container.Menu
        SIGNAUXDEPAROLEMenu          matlab.ui.container.Menu
        GnrerunprocessusAREditFieldLabel  matlab.ui.control.Label
        GnrerunprocessusAREditField  matlab.ui.control.EditField
        CALCULLFHFDropDownLabel      matlab.ui.control.Label
        CALCULLFHFDropDown           matlab.ui.control.DropDown
        Button_2                     matlab.ui.control.Button
        QuantificationDropDownLabel  matlab.ui.control.Label
        QuantificationDropDown       matlab.ui.control.DropDown
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        LordedelAREditFieldLabel     matlab.ui.control.Label
        LordedelAREditField          matlab.ui.control.EditField
        RACINESButton                matlab.ui.control.Button
        ARGUMENTSButton              matlab.ui.control.Button
        DSPButton                    matlab.ui.control.Button
        CalculdeDFAEditFieldLabel    matlab.ui.control.Label
        CalculdeDFAEditField         matlab.ui.control.EditField
        Button_8                     matlab.ui.control.Button
        MFCCEditFieldLabel           matlab.ui.control.Label
        MFCCEditField                matlab.ui.control.EditField
        Button_9                     matlab.ui.control.Button
        Button_10                    matlab.ui.control.Button
        Button_11                    matlab.ui.control.Button
        ordreEditFieldLabel          matlab.ui.control.Label
        ordreEditField               matlab.ui.control.EditField
        IMPORTERUNSIGNALButton       matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function  [racines_p_LSP,racines_q_LSP]=LSP_fct(app,ordre,coefficients)
n=ordre+1;
polynom_a_1surz = flip(coefficients);
racines_a_1surz = roots(polynom_a_1surz);

for k =1:ordre
    racines_a(k) = 1/racines_a_1surz(k);
end
if mod(ordre,2)==0
% Polynome P

polynom_p_1surz_LSP=[];
polynom_p_1surz_LSP=[polynom_p_1surz_LSP coefficients(1)];
for i=2:(n+1)/2
polynom_p_1surz_LSP =[polynom_p_1surz_LSP coefficients(i)+coefficients(n+2-i)]; 
end 
polynom_p_1surz_LSP=[polynom_p_1surz_LSP flip(polynom_p_1surz_LSP)];
racines_p_1surz_LSP = roots(polynom_p_1surz_LSP);
for k =1:n
    racines_p_LSP(k) = 1/racines_p_1surz_LSP(k);
end

% Polynome Q
polynom_q_1surz_LSP=[];
polynom_q_1surz_LSP=[polynom_q_1surz_LSP coefficients(1)];
for i=2:(n+1)/2
polynom_q_1surz_LSP =[polynom_q_1surz_LSP coefficients(i)-coefficients(n+2-i)]; 
end 
polynom_q_1surz_LSP=[polynom_q_1surz_LSP flip(-polynom_q_1surz_LSP)];
racines_q_1surz_LSP = roots(polynom_q_1surz_LSP);
for k =1:n
    racines_q_LSP(k) = 1/racines_q_1surz_LSP(k);
end
end
if mod(ordre,2)==1


% Polynome P
polynom_p_1surz_LSP=[];
polynom_p_1surz_LSP=[polynom_p_1surz_LSP coefficients(1)];
for i=2:length(coefficients)/2+1
polynom_p_1surz_LSP =[polynom_p_1surz_LSP coefficients(i)+coefficients(length(coefficients)+2-i)]; 
end 
polynom_p_1surz_LSP=[polynom_p_1surz_LSP flip(polynom_p_1surz_LSP(1:end-1))];
racines_p_1surz_LSP = roots(polynom_p_1surz_LSP)
for k =1:length(coefficients)
    racines_p_LSP(k) = 1/racines_p_1surz_LSP(k)
end
% Polynome Q
polynom_q_1surz_LSP=[];
polynom_q_1surz_LSP=[polynom_q_1surz_LSP coefficients(1)];
for i=2:length(coefficients)/2+1
polynom_q_1surz_LSP =[polynom_q_1surz_LSP coefficients(i)-coefficients(length(coefficients)+2-i)]; 
end 
polynom_q_1surz_LSP=[polynom_q_1surz_LSP flip(-polynom_q_1surz_LSP(1:end-1))];
racines_q_LSP = roots(polynom_q_1surz_LSP)

end


%Plot
blue = [0, 0.4470, 0.7410];
red = [0.8500, 0.3250, 0.0980];
yellow = [0.9290, 0.6940, 0.1250];
black = [0.25, 0.25, 0.25];

figure
% si les poles sont reels 
if imag(racines_a)==0
plot(racines_a,zeros(1,ordre),'*','MarkerSize',7,'Color',blue)
else 
%si les poles sont complexes 
 plot(racines_a,'*','MarkerSize',7,'Color',blue)   
end
hold on
plot(racines_p_LSP,'+','MarkerSize',7,'Color',red)
hold on
plot(racines_q_LSP,'o','MarkerSize',7,'Color',yellow)
title('Racines des polynomes A(z), P(z) et Q(z) (partie LSP)'); 

hold on
plot (cos([0:0.01:2*pi]),sin([0:0.01:2*pi]),'--','Color',black) % cercle unite

%Trace des rayons
 for k = 1:ordre
    hold on
    plot([0 real(racines_a(k))],[0 imag(racines_a(k))],'Color',blue,'Linewidth',1)
end   
for k =1:n
    hold on
    plot([0 real(racines_p_LSP(k))],[0 imag(racines_p_LSP(k))],'Color',red,'Linewidth',1)
    hold on
    plot([0 real(racines_q_LSP(k))],[0 imag(racines_q_LSP(k))],'Color',yellow,'Linewidth',1)
end
legend({'A(z)','P(z)','Q(z)'},'Location','eastoutside');
axis equal
ylim([-1.1 1.1]) 
    
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GnrerunprocessusAREditField
        function ButtonPushed(app, event)
            value = app.GnrerunprocessusAREditField.Value;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
        d = dialog('Position',[300 300 250 150],'Name','My Dialog');

    txt = uicontrol('Parent',d,...
               'Style','text',...
               'Position',[20 80 210 40],...
               'String','Les pôles doivent être rééls ou complexes conjuguées et de module inférieur à 1.');

    btn = uicontrol('Parent',d,...
               'Position',[85 20 70 25],...
               'String','Fermer',...
               'Callback','delete(gcf)');

        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
   
            Poles=app.GnrerunprocessusAREditField.Value;
            Poles=str2num(app.GnrerunprocessusAREditField.Value);
           
            orde=app.ordreEditField.Value;
            ordre=str2num(app.ordreEditField.Value);
            
            LSP_fct(app,ordre,Poles)
        end

        % Button pushed function: RACINESButton
        function RACINESButtonPushed(app, event)
            %LSP_fct(app,ordre,Poles)
        end

        % Button pushed function: IMPORTERUNSIGNALButton
        function IMPORTERUNSIGNALButtonPushed(app, event)
[file,path,indx]=uigetfile
 filename=[path file]
 A=importdata(filename) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SIGNAUXBIOMEDICAUXMenu = uimenu(app.UIFigure);
            app.SIGNAUXBIOMEDICAUXMenu.Text = 'SIGNAUX BIOMEDICAUX';
            app.SIGNAUXDEPAROLEMenu = uimenu(app.UIFigure);
            app.SIGNAUXDEPAROLEMenu.Text = 'SIGNAUX DE PAROLE';
            app.GnrerunprocessusAREditFieldLabel = uilabel(app.UIFigure);
            app.GnrerunprocessusAREditFieldLabel.Text = 'Générer un processus AR';
            app.GnrerunprocessusAREditField = uieditfield(app.UIFigure, 'text');
            app.GnrerunprocessusAREditField.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GnrerunprocessusAREditField.Value = ' ';
            app.CALCULLFHFDropDownLabel = uilabel(app.UIFigure);
            app.CALCULLFHFDropDownLabel.Text = 'CALCUL LF/HF';
            app.CALCULLFHFDropDown = uidropdown(app.UIFigure);
            app.CALCULLFHFDropDown.Items = {'Haute résolution', 'Basse résolution'};
            app.CALCULLFHFDropDown.Value = 'Haute résolution';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'intero.jpeg';
            app.Button_2.Text = '';
            app.QuantificationDropDownLabel = uilabel(app.UIFigure);
            app.QuantificationDropDownLabel.Text = 'Quantification';
            app.QuantificationDropDown = uidropdown(app.UIFigure);
            app.QuantificationDropDown.Items = {'LSP', 'ISP'};
            app.QuantificationDropDown.Value = 'LSP';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'intero.jpeg';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Icon = 'intero.jpeg';
            app.Button_4.Text = '';
            app.LordedelAREditFieldLabel = uilabel(app.UIFigure);
            app.LordedelAREditFieldLabel.Text = 'L''orde de l''AR';
            app.LordedelAREditField = uieditfield(app.UIFigure, 'text');
            app.RACINESButton = uibutton(app.UIFigure, 'push');
            app.RACINESButton.ButtonPushedFcn = createCallbackFcn(app, @RACINESButtonPushed, true);
            app.RACINESButton.Text = 'RACINES ';
            app.ARGUMENTSButton = uibutton(app.UIFigure, 'push');
            app.ARGUMENTSButton.Text = 'ARGUMENTS';
            app.DSPButton = uibutton(app.UIFigure, 'push');
            app.DSPButton.Text = 'DSP';
            app.CalculdeDFAEditFieldLabel = uilabel(app.UIFigure);
            app.CalculdeDFAEditFieldLabel.Text = 'Calcul de DFA';
            app.CalculdeDFAEditField = uieditfield(app.UIFigure, 'text');
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Icon = 'intero.jpeg';
            app.Button_8.Text = '';
            app.MFCCEditFieldLabel = uilabel(app.UIFigure);
            app.MFCCEditFieldLabel.Text = 'MFCC';
            app.MFCCEditField = uieditfield(app.UIFigure, 'text');
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Icon = 'intero.jpeg';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'check.png';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Icon = 'logo_sigbox.png';
            app.Button_11.Text = '';
            app.ordreEditFieldLabel = uilabel(app.UIFigure);
            app.ordreEditFieldLabel.Text = 'ordre:';
            app.ordreEditField = uieditfield(app.UIFigure, 'text');
            app.IMPORTERUNSIGNALButton = uibutton(app.UIFigure, 'push');
            app.IMPORTERUNSIGNALButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTERUNSIGNALButtonPushed, true);
            app.IMPORTERUNSIGNALButton.Text = 'IMPORTER UN SIGNAL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end